classdef APPUPDATED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        StartupScreenTab  matlab.ui.container.Tab
        Label_7           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        SPTCLabel         matlab.ui.control.Label
        TakeTestButton    matlab.ui.control.Button
        Test1Tab          matlab.ui.container.Tab
        NextButton        matlab.ui.control.Button
        Label_5           matlab.ui.control.Label
        Q6                matlab.ui.control.ListBox
        ListBoxLabel_5    matlab.ui.control.Label
        Q4                matlab.ui.control.ListBox
        ListBoxLabel_4    matlab.ui.control.Label
        Q5                matlab.ui.control.ListBox
        ListBoxLabel_3    matlab.ui.control.Label
        Q1                matlab.ui.control.ListBox
        ListBoxLabel_2    matlab.ui.control.Label
        Q2                matlab.ui.control.ListBox
        ListBoxLabel      matlab.ui.control.Label
        Q3                matlab.ui.control.ListBox
        Label             matlab.ui.control.Label
        Test2Tab          matlab.ui.container.Tab
        Label_8           matlab.ui.control.Label
        Q12               matlab.ui.control.ListBox
        ListBoxLabel_10   matlab.ui.control.Label
        Q10               matlab.ui.control.ListBox
        ListBoxLabel_9    matlab.ui.control.Label
        Q11               matlab.ui.control.ListBox
        ListBoxLabel_8    matlab.ui.control.Label
        Q7                matlab.ui.control.ListBox
        ListBoxLabel_7    matlab.ui.control.Label
        Q8                matlab.ui.control.ListBox
        Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesLabel  matlab.ui.control.Label
        Q9                matlab.ui.control.ListBox
        ListBoxLabel_6    matlab.ui.control.Label
        Test3Tab          matlab.ui.container.Tab
        Label_9           matlab.ui.control.Label
        Q18               matlab.ui.control.ListBox
        Q12IfeelageneralsenseoffearanxietybeforeIperformListBox_2Label  matlab.ui.control.Label
        Q16               matlab.ui.control.ListBox
        ListBoxLabel_13   matlab.ui.control.Label
        Q17               matlab.ui.control.ListBox
        ListBoxLabel_12   matlab.ui.control.Label
        Q13               matlab.ui.control.ListBox
        Q13IhavementalworriesbeforeperformingListBoxLabel  matlab.ui.control.Label
        Q14               matlab.ui.control.ListBox
        Q14ThinkingoffailurecausesphysicalstressreactionsinmybodyLabel  matlab.ui.control.Label
        Q15               matlab.ui.control.ListBox
        ListBoxLabel_11   matlab.ui.control.Label
        Test4Tab          matlab.ui.container.Tab
        Label_10          matlab.ui.control.Label
        Q24               matlab.ui.control.ListBox
        ListBoxLabel_18   matlab.ui.control.Label
        Q22               matlab.ui.control.ListBox
        ListBoxLabel_17   matlab.ui.control.Label
        Q23               matlab.ui.control.ListBox
        ListBoxLabel_16   matlab.ui.control.Label
        Q19               matlab.ui.control.ListBox
        Q13IhavementalworriesbeforeperformingListBox_2Label  matlab.ui.control.Label
        Q20               matlab.ui.control.ListBox
        ListBoxLabel_15   matlab.ui.control.Label
        Q21               matlab.ui.control.ListBox
        ListBoxLabel_14   matlab.ui.control.Label
        Test5Tab          matlab.ui.container.Tab
        Label_11          matlab.ui.control.Label
        Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3  matlab.ui.control.ListBox
        ListBoxLabel_23   matlab.ui.control.Label
        Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4  matlab.ui.control.ListBox
        ListBoxLabel_22   matlab.ui.control.Label
        Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4  matlab.ui.control.ListBox
        ListBoxLabel_21   matlab.ui.control.Label
        Q13IhavementalworriesbeforeperformingListBox_3  matlab.ui.control.ListBox
        Q13IhavementalworriesbeforeperformingListBox_2Label_2  matlab.ui.control.Label
        Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4  matlab.ui.control.ListBox
        ListBoxLabel_20   matlab.ui.control.Label
        Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4  matlab.ui.control.ListBox
        ListBoxLabel_19   matlab.ui.control.Label
        Test6Tab          matlab.ui.container.Tab
        FINISHTESTButton  matlab.ui.control.Button
        Label_12          matlab.ui.control.Label
        Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5  matlab.ui.control.ListBox
        ListBoxLabel_27   matlab.ui.control.Label
        Q13IhavementalworriesbeforeperformingListBox_4  matlab.ui.control.ListBox
        Q13IhavementalworriesbeforeperformingListBox_2Label_3  matlab.ui.control.Label
        Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5  matlab.ui.control.ListBox
        ListBoxLabel_25   matlab.ui.control.Label
        Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5  matlab.ui.control.ListBox
        ListBoxLabel_24   matlab.ui.control.Label
    end

    
    properties (Access = private)
        response % User 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function strtFcn(app)
            app.response = zeros(1,34);
        end

        % Button pushed function: TakeTestButton
        function TakeTest(app, event)
            app.TabGroup.SelectedTab = app.Test1Tab;
        end

        % Callback function
        function OpenTest(app, event)
            
        end

        % Value changed function: Q1
        function q1fcn(app, event)
            value = app.Q1.Value;
            switch value
                case 1
                    app.response(1) = 1;
                case 2
                    app.response(1) = 2;
                case 3 
                    app.response(1) = 3;
            
            end
        end

        % Value changed function: Q3
        function q3fcn(app, event)
            value = app.Q3.Value;
            switch value
                case 1
                    app.response(3) = 1;
                case 2
                    app.response(3) = 2;
                case 3
                    app.response(3) = 3;
            end
        end

        % Value changed function: Q4
        function q4fcn(app, event)
            value = app.Q4.Value;
            switch value
                case 1
                    app.response(4) = 1;
                case 2
                    app.response(4) = 2;
                case 3
                    app.response(4) = 3;
            end
    
        end

        % Value changed function: Q5
        function q5fcn(app, event)
            value = app.Q5.Value;
            switch value
                case 1 
                    app.response(5) = 1;
                case 2 
                    app.response(5) = 2;
                case 3 
                    app.response(5) = 3;
            end
        end

        % Value changed function: Q6
        function q6fcn(app, event)
            value = app.Q6.Value;
            switch value
                case 1 
                    app.response(6) = 1;
                case 2
                    app.response(6) = 2;
                case 3
                    app.response(6) = 3;
            end
            
        end

        % Value changed function: Q2
        function q2fcn(app, event)
            value = app.Q2.Value;
            switch value
                case 1
                    app.response(2) = 1;
                case 2
                    app.response(2) = 2;
                case 3
                    app.response(2) = 3;
            end    
        end

        % Value changed function: Q7
        function q7fcn(app, event)
            value = app.Q7.Value;
            switch value
                case 1
                    app.response(7) = 1;
                case 2
                    app.response(7) = 2;
                case 3
                    app.response(7) = 3;
            end   
        end

        % Value changed function: Q8
        function q8fcn(app, event)
            value = app.Q8.Value;
            switch value
                case 1
                    app.response(8) = 1;
                case 2
                    app.response(8) = 2;
                case 3
                    app.response(8) = 3;
            end   
        end

        % Value changed function: Q9
        function q9fcn(app, event)
            value = app.Q9.Value;
            switch value
                case 1
                    app.response(9) = 1;
                case 2
                    app.response(9) = 2;
                case 3
                    app.response(9) = 3;
            end   
        end

        % Value changed function: Q10
        function q10fcn(app, event)
            value = app.Q10.Value;
            switch value
                case 1
                    app.response(10) = 1;
                case 2
                    app.response(10) = 2;
                case 3
                    app.response(10) = 3;
            end   
        end

        % Value changed function: Q11
        function q11fcn(app, event)
            value = app.Q11.Value;
            switch value
                case 1
                    app.response(11) = 1;
                case 2
                    app.response(11) = 2;
                case 3
                    app.response(11) = 3;
            end   
        end

        % Value changed function: Q12
        function q12fcn(app, event)
            value = app.Q12.Value;
             switch value
                case 1
                    app.response(12) = 1;
                case 2
                    app.response(12) = 2;
                case 3
                    app.response(12) = 3;
            end   
        end

        % Value changed function: Q13
        function q13fcn(app, event)
            value = app.Q13.Value;
             switch value
                case 1
                    app.response(13) = 1;
                case 2
                    app.response(13) = 2;
                case 3
                    app.response(13) = 3;
            end   
        end

        % Value changed function: Q14
        function q14fcn(app, event)
            value = app.Q14.Value;
             switch value
                case 1
                    app.response(14) = 1;
                case 2
                    app.response(14) = 2;
                case 3
                    app.response(14) = 3;
            end   
        end

        % Value changed function: Q15
        function q15fcn(app, event)
            value = app.Q15.Value;
             switch value
                case 1
                    app.response(15) = 1;
                case 2
                    app.response(15) = 2;
                case 3
                    app.response(15) = 3;
            end   
        end

        % Value changed function: Q16
        function q16fcn(app, event)
            value = app.Q16.Value;
             switch value
                case 1
                    app.response(16) = 1;
                case 2
                    app.response(16) = 2;
                case 3
                    app.response(16) = 3;
            end   
        end

        % Value changed function: Q17
        function q17fcn(app, event)
            value = app.Q17.Value;
             switch value
                case 1
                    app.response(17) = 1;
                case 2
                    app.response(17) = 2;
                case 3
                    app.response(17) = 3;
            end   
        end

        % Value changed function: Q18
        function q18fcn(app, event)
            value = app.Q18.Value;
             switch value
                case 1
                    app.response(18) = 1;
                case 2
                    app.response(18) = 2;
                case 3
                    app.response(18) = 3;
            end   
        end

        % Value changed function: Q19
        function q19fcn(app, event)
            value = app.Q19.Value;
             switch value
                case 1
                    app.response(19) = 1;
                case 2
                    app.response(19) = 2;
                case 3
                    app.response(19) = 3;
            end   
        end

        % Value changed function: Q20
        function q20fcn(app, event)
            value = app.Q20.Value;
             switch value
                case 1
                    app.response(20) = 1;
                case 2
                    app.response(20) = 2;
                case 3
                    app.response(20) = 3;
            end   
        end

        % Value changed function: Q21
        function q21fcn(app, event)
            value = app.Q21.Value;
            switch value
                case 1
                    app.response(21) = 1;
                case 2
                    app.response(21) = 2;
                case 3
                    app.response(21) = 3;
            end   
        end

        % Value changed function: Q22
        function q22fcn(app, event)
            value = app.Q22.Value;
            switch value
                case 1
                    app.response(22) = 1;
                case 2
                    app.response(22) = 2;
                case 3
                    app.response(22) = 3;
            end   
        end

        % Value changed function: Q23
        function q23fcn(app, event)
            value = app.Q23.Value;
            switch value
                case 1
                    app.response(23) = 1;
                case 2
                    app.response(23) = 2;
                case 3
                    app.response(23) = 3;
            end   
        end

        % Value changed function: Q24
        function q24fcn(app, event)
            value = app.Q24.Value;
            switch value
                case 1
                    app.response(24) = 1;
                case 2
                    app.response(24) = 2;
                case 3
                    app.response(24) = 3;
            end   
        end

        % Value changed function: 
        % Q13IhavementalworriesbeforeperformingListBox_3
        function q25fcn(app, event)
            value = app.Q13IhavementalworriesbeforeperformingListBox_3.Value;
            switch value
                case 1
                    app.response(25) = 1;
                case 2
                    app.response(25) = 2;
                case 3
                    app.response(25) = 3;
            end   
        end

        % Value changed function: 
        % Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4
        function q26fcn(app, event)
            value = app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4.Value;
            switch value
                case 1
                    app.response(26) = 1;
                case 2
                    app.response(26) = 2;
                case 3
                    app.response(26) = 3;
            end   
        end

        % Value changed function: 
        % Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4
        function q27fcn(app, event)
            value = app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4.Value;
            switch value
                case 1
                    app.response(27) = 1;
                case 2
                    app.response(27) = 2;
                case 3
                    app.response(27) = 3;
            end   
        end

        % Value changed function: 
        % Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4
        function q28fcn(app, event)
            value = app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Value;
            switch value
                case 1
                    app.response(28) = 1;
                case 2
                    app.response(28) = 2;
                case 3
                    app.response(28) = 3;
            end   
        end

        % Value changed function: 
        % Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4
        function q29fcn(app, event)
            value = app.Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Value;
            switch value
                case 1
                    app.response(29) = 1;
                case 2
                    app.response(29) = 2;
                case 3
                    app.response(29) = 3;
            end   
        end

        % Value changed function: 
        % Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3
        function q30fcn(app, event)
            value = app.Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3.Value;
            switch value
                case 1
                    app.response(30) = 1;
                case 2
                    app.response(30) = 2;
                case 3
                    app.response(30) = 3;
            end   
        end

        % Value changed function: 
        % Q13IhavementalworriesbeforeperformingListBox_4
        function q31fcn(app, event)
            value = app.Q13IhavementalworriesbeforeperformingListBox_4.Value;
            switch value
                case 1
                    app.response(31) = 1;
                case 2
                    app.response(31) = 2;
                case 3
                    app.response(31) = 3;
            end  
        end

        % Value changed function: 
        % Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5
        function q32fcn(app, event)
            value = app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5.Value;
            switch value
                case 1
                    app.response(32) = 1;
                case 2
                    app.response(32) = 2;
                case 3
                    app.response(32) = 3;
            end  
        end

        % Value changed function: 
        % Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5
        function q33fcn(app, event)
            value = app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5.Value;
            switch value
                case 1
                    app.response(33) = 1;
                case 2
                    app.response(33) = 2;
                case 3
                    app.response(33) = 3;
            end  
        end

        % Value changed function: 
        % Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5
        function q34fcn(app, event)
            value = app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5.Value;
            switch value
                case 1
                    app.response(34) = 1;
                case 2
                    app.response(34) = 2;
                case 3
                    app.response(34) = 3;
            end  
        end

        % Button pushed function: FINISHTESTButton
        function finishFnct(app, event)
            output = eTree.predict(app.response);
            
        end

        % Button pushed function: NextButton
        function page1fcn(app, event)
            app.TabGroup.SelectedTab = app.Test2Tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartupScreenTab = uitab(app.TabGroup);
            app.StartupScreenTab.Title = 'StartupScreen';
            app.TakeTestButton = uibutton(app.StartupScreenTab, 'push');
            app.TakeTestButton.ButtonPushedFcn = createCallbackFcn(app, @TakeTest, true);
            app.TakeTestButton.Text = 'Take Test';
            app.SPTCLabel = uilabel(app.StartupScreenTab);
            app.SPTCLabel.Text = 'SPTC';
            app.Label_2 = uilabel(app.StartupScreenTab);
            app.Label_2.Text = {'The test consists of 34 questions '; 'about general scenarios of '; 'stress and performance. You will be prompted to '; 'answer with "Not like me," "A little like me," or "Exactly like me." '; 'Choose the option that best explains how you feel in times '; 'of stress and/or performance. '};
            app.Label_3 = uilabel(app.StartupScreenTab);
            app.Label_3.Text = {'The SPTC stands for "Stress and Performance'; 'Theory Classifier." Performance refers to '; 'any situation where your abilities, '; 'knowledge or skills are being tested- whether that '; 'be an exam or sports competition. To find '; 'out which theory of stress and performance '; 'applies to you individually, '; 'take the test below.'};
            app.Label_6 = uilabel(app.StartupScreenTab);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.StartupScreenTab);
            app.Label_7.Text = '';
            app.Test1Tab = uitab(app.TabGroup);
            app.Test1Tab.Title = 'Test1';
            app.Label = uilabel(app.Test1Tab);
            app.Label.Text = {'Q3: I am able to categorize my'; 'anxiety into its different '; 'symptoms'};
            app.Q3 = uilistbox(app.Test1Tab);
            app.Q3.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q3.ValueChangedFcn = createCallbackFcn(app, @q3fcn, true);
            app.Q3.Value = {};
            app.ListBoxLabel = uilabel(app.Test1Tab);
            app.ListBoxLabel.Text = {'Q2: I don''t understand my stress '; 'and anxiety well'};
            app.Q2 = uilistbox(app.Test1Tab);
            app.Q2.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q2.ValueChangedFcn = createCallbackFcn(app, @q2fcn, true);
            app.Q2.Value = {};
            app.ListBoxLabel_2 = uilabel(app.Test1Tab);
            app.ListBoxLabel_2.Text = {'Q1: I feel heightened levels of '; 'motivation before performing'};
            app.Q1 = uilistbox(app.Test1Tab);
            app.Q1.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q1.ValueChangedFcn = createCallbackFcn(app, @q1fcn, true);
            app.Q1.Value = {};
            app.ListBoxLabel_3 = uilabel(app.Test1Tab);
            app.ListBoxLabel_3.Text = {'Q5: How confident I am in my abilities '; 'greatly affects my performance'};
            app.Q5 = uilistbox(app.Test1Tab);
            app.Q5.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q5.ValueChangedFcn = createCallbackFcn(app, @q5fcn, true);
            app.Q5.Value = {};
            app.ListBoxLabel_4 = uilabel(app.Test1Tab);
            app.ListBoxLabel_4.Text = {'Q4: My palms feel sweaty/hands feel shaky '; 'before performing'};
            app.Q4 = uilistbox(app.Test1Tab);
            app.Q4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q4.ValueChangedFcn = createCallbackFcn(app, @q4fcn, true);
            app.Q4.Value = {};
            app.ListBoxLabel_5 = uilabel(app.Test1Tab);
            app.ListBoxLabel_5.Text = {'Q6: Anxiety has no effect on my performance '; 'if I''m skilled at the task'};
            app.Q6 = uilistbox(app.Test1Tab);
            app.Q6.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q6.ValueChangedFcn = createCallbackFcn(app, @q6fcn, true);
            app.Q6.Value = {};
            app.Label_5 = uilabel(app.Test1Tab);
            app.Label_5.Text = '';
            app.NextButton = uibutton(app.Test1Tab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @page1fcn, true);
            app.NextButton.Text = 'Next ';
            app.Test2Tab = uitab(app.TabGroup);
            app.Test2Tab.Title = 'Test2';
            app.ListBoxLabel_6 = uilabel(app.Test2Tab);
            app.ListBoxLabel_6.Text = {'Q9: How I interpret my anxiety is more '; 'important than the intensity of the anxiety'};
            app.Q9 = uilistbox(app.Test2Tab);
            app.Q9.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q9.ValueChangedFcn = createCallbackFcn(app, @q9fcn, true);
            app.Q9.Value = {};
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesLabel = uilabel(app.Test2Tab);
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesLabel.Text = {'Q8: When my anxiety increases, my ability '; 'to focus decreases'};
            app.Q8 = uilistbox(app.Test2Tab);
            app.Q8.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q8.ValueChangedFcn = createCallbackFcn(app, @q8fcn, true);
            app.Q8.Value = {};
            app.ListBoxLabel_7 = uilabel(app.Test2Tab);
            app.ListBoxLabel_7.Text = {'Q7 : Stress doesn''t affect my performance'; 'with simple tasks'};
            app.Q7 = uilistbox(app.Test2Tab);
            app.Q7.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q7.ValueChangedFcn = createCallbackFcn(app, @q7fcn, true);
            app.Q7.Value = {};
            app.ListBoxLabel_8 = uilabel(app.Test2Tab);
            app.ListBoxLabel_8.Text = {'Q11: When I "psyche" myself up, I focus'; 'on increasing my motivation/drive'};
            app.Q11 = uilistbox(app.Test2Tab);
            app.Q11.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q11.ValueChangedFcn = createCallbackFcn(app, @q11fcn, true);
            app.Q11.Value = {};
            app.ListBoxLabel_9 = uilabel(app.Test2Tab);
            app.ListBoxLabel_9.Text = {'Q10: My performance  isn''t changed by '; 'anxiety, but my levels of attention, energy, '; 'and emotion are'};
            app.Q10 = uilistbox(app.Test2Tab);
            app.Q10.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q10.ValueChangedFcn = createCallbackFcn(app, @q10fcn, true);
            app.Q10.Value = {};
            app.ListBoxLabel_10 = uilabel(app.Test2Tab);
            app.ListBoxLabel_10.Text = {'Q12: I feel a general sense of '; 'fear/anxiety before I perform'};
            app.Q12 = uilistbox(app.Test2Tab);
            app.Q12.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q12.ValueChangedFcn = createCallbackFcn(app, @q12fcn, true);
            app.Q12.Value = {};
            app.Label_8 = uilabel(app.Test2Tab);
            app.Label_8.Text = '';
            app.Test3Tab = uitab(app.TabGroup);
            app.Test3Tab.Title = 'Test3';
            app.ListBoxLabel_11 = uilabel(app.Test3Tab);
            app.ListBoxLabel_11.Text = {'Q15: How good I feel about myself'; 'can change how I perform'};
            app.Q15 = uilistbox(app.Test3Tab);
            app.Q15.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q15.ValueChangedFcn = createCallbackFcn(app, @q15fcn, true);
            app.Q15.Value = {};
            app.Q14ThinkingoffailurecausesphysicalstressreactionsinmybodyLabel = uilabel(app.Test3Tab);
            app.Q14ThinkingoffailurecausesphysicalstressreactionsinmybodyLabel.Text = {'Q14: Thinking of failure causes physical'; 'stress reactions in my body'};
            app.Q14 = uilistbox(app.Test3Tab);
            app.Q14.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q14.ValueChangedFcn = createCallbackFcn(app, @q14fcn, true);
            app.Q14.Value = {};
            app.Q13IhavementalworriesbeforeperformingListBoxLabel = uilabel(app.Test3Tab);
            app.Q13IhavementalworriesbeforeperformingListBoxLabel.Text = {'Q13: I have mental worries before '; 'performing'};
            app.Q13 = uilistbox(app.Test3Tab);
            app.Q13.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q13.ValueChangedFcn = createCallbackFcn(app, @q13fcn, true);
            app.Q13.Value = {};
            app.ListBoxLabel_12 = uilabel(app.Test3Tab);
            app.ListBoxLabel_12.Text = {'Q17: Stress worsens my performance with '; 'complex tasks '; ''};
            app.Q17 = uilistbox(app.Test3Tab);
            app.Q17.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q17.ValueChangedFcn = createCallbackFcn(app, @q17fcn, true);
            app.Q17.Value = {};
            app.ListBoxLabel_13 = uilabel(app.Test3Tab);
            app.ListBoxLabel_13.Text = {'Q16: If I practice a task every day, '; 'then even the most extreme of worries '; 'won''t change how well I perform'; ''};
            app.Q16 = uilistbox(app.Test3Tab);
            app.Q16.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q16.ValueChangedFcn = createCallbackFcn(app, @q16fcn, true);
            app.Q16.Value = {};
            app.Q12IfeelageneralsenseoffearanxietybeforeIperformListBox_2Label = uilabel(app.Test3Tab);
            app.Q12IfeelageneralsenseoffearanxietybeforeIperformListBox_2Label.Text = {'Q18: The more stressed I am, the '; 'easier it is for me to get distracted '; ''};
            app.Q18 = uilistbox(app.Test3Tab);
            app.Q18.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q18.ValueChangedFcn = createCallbackFcn(app, @q18fcn, true);
            app.Q18.Value = {};
            app.Label_9 = uilabel(app.Test3Tab);
            app.Label_9.Text = '';
            app.Test4Tab = uitab(app.TabGroup);
            app.Test4Tab.Title = 'Test4';
            app.ListBoxLabel_14 = uilabel(app.Test4Tab);
            app.ListBoxLabel_14.Text = {'Q21: Believing that I can do something '; 'increases my ability to do it '; ''};
            app.Q21 = uilistbox(app.Test4Tab);
            app.Q21.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q21.ValueChangedFcn = createCallbackFcn(app, @q21fcn, true);
            app.Q21.Value = {};
            app.ListBoxLabel_15 = uilabel(app.Test4Tab);
            app.ListBoxLabel_15.Text = {'Q20: When I feel anxious I need to put in more '; 'effort to get an optimal level of performance '; ''};
            app.Q20 = uilistbox(app.Test4Tab);
            app.Q20.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q20.ValueChangedFcn = createCallbackFcn(app, @q20fcn, true);
            app.Q20.Value = {};
            app.Q13IhavementalworriesbeforeperformingListBox_2Label = uilabel(app.Test4Tab);
            app.Q13IhavementalworriesbeforeperformingListBox_2Label.Text = {'Q19: If I feel positively about my stress '; 'levels, I will perform better than if I'; ' feel negatively about my stress levels '};
            app.Q19 = uilistbox(app.Test4Tab);
            app.Q19.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q19.ValueChangedFcn = createCallbackFcn(app, @q19fcn, true);
            app.Q19.Value = {};
            app.ListBoxLabel_16 = uilabel(app.Test4Tab);
            app.ListBoxLabel_16.Text = {'Q23: When I am performing a stressful task, '; 'my attention shifts inward and I focus on '; 'small details more than the overall task '; ''; ''};
            app.Q23 = uilistbox(app.Test4Tab);
            app.Q23.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q23.ValueChangedFcn = createCallbackFcn(app, @q23fcn, true);
            app.Q23.Value = {};
            app.ListBoxLabel_17 = uilabel(app.Test4Tab);
            app.ListBoxLabel_17.Text = {'Q22: I become more aware of how anxious '; 'I am before performing a difficult task'; ''; ''};
            app.Q22 = uilistbox(app.Test4Tab);
            app.Q22.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q22.ValueChangedFcn = createCallbackFcn(app, @q22fcn, true);
            app.Q22.Value = {};
            app.ListBoxLabel_18 = uilabel(app.Test4Tab);
            app.ListBoxLabel_18.Text = {'Q24: I tend to interpret low anxiety as '; '"boredom" and high anxiety as "stress" '; '(negative associations)'; ''; ''};
            app.Q24 = uilistbox(app.Test4Tab);
            app.Q24.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q24.ValueChangedFcn = createCallbackFcn(app, @q24fcn, true);
            app.Q24.Value = {};
            app.Label_10 = uilabel(app.Test4Tab);
            app.Label_10.Text = '';
            app.Test5Tab = uitab(app.TabGroup);
            app.Test5Tab.Title = 'Test5';
            app.ListBoxLabel_19 = uilabel(app.Test5Tab);
            app.ListBoxLabel_19.Text = {'Q27: I am unable to pinpoint what my anxiety '; 'symptoms are '; ''};
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4 = uilistbox(app.Test5Tab);
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4.ValueChangedFcn = createCallbackFcn(app, @q27fcn, true);
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_4.Value = {};
            app.ListBoxLabel_20 = uilabel(app.Test5Tab);
            app.ListBoxLabel_20.Text = {'Q26: All I need to do to increase my '; 'performance quality is make myself more '; 'motivated'; ''};
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4 = uilistbox(app.Test5Tab);
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4.ValueChangedFcn = createCallbackFcn(app, @q26fcn, true);
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_4.Value = {};
            app.Q13IhavementalworriesbeforeperformingListBox_2Label_2 = uilabel(app.Test5Tab);
            app.Q13IhavementalworriesbeforeperformingListBox_2Label_2.Text = {'Q25: The more anxious I am, the more '; 'energy I need to use'; ''};
            app.Q13IhavementalworriesbeforeperformingListBox_3 = uilistbox(app.Test5Tab);
            app.Q13IhavementalworriesbeforeperformingListBox_3.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q13IhavementalworriesbeforeperformingListBox_3.ValueChangedFcn = createCallbackFcn(app, @q25fcn, true);
            app.Q13IhavementalworriesbeforeperformingListBox_3.Value = {};
            app.ListBoxLabel_21 = uilabel(app.Test5Tab);
            app.ListBoxLabel_21.Text = {'Q29: When I''m stressed I often mistake '; 'the stress for sickness'; ''; ''};
            app.Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4 = uilistbox(app.Test5Tab);
            app.Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.ValueChangedFcn = createCallbackFcn(app, @q29fcn, true);
            app.Q11IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Value = {};
            app.ListBoxLabel_22 = uilabel(app.Test5Tab);
            app.ListBoxLabel_22.Text = {'Q28: I have doubts, fears, and worries during'; ' a performance '; ''; ''};
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4 = uilistbox(app.Test5Tab);
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.ValueChangedFcn = createCallbackFcn(app, @q28fcn, true);
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_4.Value = {};
            app.ListBoxLabel_23 = uilabel(app.Test5Tab);
            app.ListBoxLabel_23.Text = {'Q30: Believing that I can''t do something '; 'decreases my ability to do it '; ''; ''};
            app.Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3 = uilistbox(app.Test5Tab);
            app.Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3.ValueChangedFcn = createCallbackFcn(app, @q30fcn, true);
            app.Q18ThemorestressedIamtheeasieritisformetogetdistractedListBox_3.Value = {};
            app.Label_11 = uilabel(app.Test5Tab);
            app.Label_11.Text = '';
            app.Test6Tab = uitab(app.TabGroup);
            app.Test6Tab.Title = 'Test6';
            app.ListBoxLabel_24 = uilabel(app.Test6Tab);
            app.ListBoxLabel_24.Text = {'Q33: When I am anxious I become hyper aware '; 'of the way I am performing a task '; ''};
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5 = uilistbox(app.Test6Tab);
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5.ValueChangedFcn = createCallbackFcn(app, @q33fcn, true);
            app.Q9IamabletocategorizemyanxietyintoitsdifferentsymptomsListBox_5.Value = {};
            app.ListBoxLabel_25 = uilabel(app.Test6Tab);
            app.ListBoxLabel_25.Text = {'Q32: I tend to focus more on how difficult a '; 'task is opposed to how "calm" or "ready" I am '; ''};
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5 = uilistbox(app.Test6Tab);
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5.ValueChangedFcn = createCallbackFcn(app, @q32fcn, true);
            app.Q8WhenmyanxietyincreasesmyabilitytofocusdecreasesListBox_5.Value = {};
            app.Q13IhavementalworriesbeforeperformingListBox_2Label_3 = uilabel(app.Test6Tab);
            app.Q13IhavementalworriesbeforeperformingListBox_2Label_3.Text = {'Q31: I tend to freeze up, or "choke," when '; 'there''s pressure to do something I''m not '; 'skilled at'; ''};
            app.Q13IhavementalworriesbeforeperformingListBox_4 = uilistbox(app.Test6Tab);
            app.Q13IhavementalworriesbeforeperformingListBox_4.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q13IhavementalworriesbeforeperformingListBox_4.ValueChangedFcn = createCallbackFcn(app, @q31fcn, true);
            app.Q13IhavementalworriesbeforeperformingListBox_4.Value = {};
            app.ListBoxLabel_27 = uilabel(app.Test6Tab);
            app.ListBoxLabel_27.Text = {'Q34: Attempting to perform while feeling '; 'high anxiety takes a lot of energy '; ''; ''};
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5 = uilistbox(app.Test6Tab);
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5.Items = {'Not like me', 'A little like me ', 'Exactly like me'};
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5.ValueChangedFcn = createCallbackFcn(app, @q34fcn, true);
            app.Q10IfeelheightenedlevelsofmotivationbeforeperformingListBox_5.Value = {};
            app.Label_12 = uilabel(app.Test6Tab);
            app.Label_12.Text = '';
            app.FINISHTESTButton = uibutton(app.Test6Tab, 'push');
            app.FINISHTESTButton.ButtonPushedFcn = createCallbackFcn(app, @finishFnct, true);
            app.FINISHTESTButton.Text = 'FINISH TEST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPUPDATED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @strtFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end